classdef HW1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        lab_scoreBar  matlab.ui.control.Label
        lab_bl        matlab.ui.control.Label
        lab_br        matlab.ui.control.Label
        lab_tl        matlab.ui.control.Label
        lab_tr        matlab.ui.control.Label
        lab_title     matlab.ui.control.Label
        but_br        matlab.ui.control.Button
        but_bl        matlab.ui.control.Button
        but_tr        matlab.ui.control.Button
        but_tl        matlab.ui.control.Button
    end

    
    properties (Access = private)
        m_img; % Image selected to be displayed by user
        m_IconParts % cell array contains 4 slices of the image as the icon of the 4 buttons
        m_RedSeq; % the order the button turning red
        m_RedButtonIdx; % an int array of the buttons indices that has turned red
        m_HittedRedButton % a bool mask of which button has turned red and has been pressed
        m_HitDelay; % double array recording the time it takes for the user to hit each red button after it turns red
        m_RedTime; % uint64 array storing the time each button turns red using tic function

        m_Buttons; % cell array collecting all ui button objects to enable button indexing
        m_Labels; % cell array collecting all ui label objects to enable button indexing
    end
    
    properties (Access = public)
        AllRedButtonPressed % flag checking if all the button turned red are pressed. Must be public so the waitfor function can check its value
    end
    
    methods (Access = private)
        function m_SetButtonIcon(app)
            % Prompts the user to select an image to be used as the icons
            % of each button.
            % The selected image will be splitted into 4 parts and stored
            % into cell array app.m_IconParts as double arrays

            [fName, pName] = uigetfile({'*.png'; '*.jpeg'; '*.jpg'}, "MultiSelect", "off");
            app.m_img = imread([pName  fName]);
            [nRow, nCol, ~] = size(app.m_img);
            imgVrtBoundaryIdx = floor(nRow / 2);
            imgHozBoundaryIdx = floor(nCol / 2);
            
            app.m_IconParts{1} = app.m_img(1 : imgVrtBoundaryIdx, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{2} = app.m_img(1 :imgVrtBoundaryIdx, imgHozBoundaryIdx : end, :);
            app.m_IconParts{3} = app.m_img(imgVrtBoundaryIdx : end, 1 : imgHozBoundaryIdx, :);
            app.m_IconParts{4} = app.m_img(imgVrtBoundaryIdx : end, imgHozBoundaryIdx : end, :);
        end
        
        function m_PlayGame(app)
            % Controls which button turns red and stores the time at which
            % each button turns red into property m_RedTime. 
            % Also, keeps track of which button have turned red and store
            % its index into property m_RedButtonIdx
            % When all the buttons have turned red and have been pressed,
            % exit this function 

            for hit = 1:4
                pause(1.5); % buttons turn red every 1.5 seconds
                app.m_RedButtonIdx(hit) = app.m_RedSeq(hit); % store the index of the button that has turned red into property m_RedButtonIdx
                app.m_Buttons{app.m_RedSeq(hit)}.BackgroundColor = 'r'; % turn the indexed button red
                app.m_RedTime(app.m_RedSeq(hit)) = tic; % get the current time stamp
            end

            waitfor(app, 'AllRedButtonPressed', true);
        end

        function m_MatchButtonPressed(app, buttonIdx)
            % This function is called by the callback function of each
            % button. Each button has its unique index:
            %       Top_Left button = 1
            %       Top_Rigth button = 2
            %       Botton Left button = 3
            %       Botton Right button = 4
            % If the index of the button pressed is contained in property
            % m_RedButtonIdx, calculate the time elasped since it has
            % turned red, store the result into property m_HitDelay and
            % set the hitted flag for this button in property
            % m_HittedRedButton to true. When all flags in property
            % m_HittedRedButton are true, set flag AllRedButtonPressed true
            % and exit waitfor in m_PlayGame

            if ismember(buttonIdx, app.m_RedButtonIdx)
                app.m_HitDelay(buttonIdx) = toc(app.m_RedTime(buttonIdx));
                app.m_DisplayHitDelay(buttonIdx);
                app.m_Buttons{buttonIdx}.Icon = app.m_IconParts{app.m_RedButtonIdx};
                app.m_HittedRedButton(buttonIdx) = true;
                if app.m_HittedRedButton
                    app.AllRedButtonPressed = true;
                end
            end
            
        end
        
        function m_DisplayHitDelay(app, buttonIdx)
            app.m_Labels{buttonIdx}.Text = sprintf('%.3f', app.m_HitDelay(buttonIdx));
            app.m_Labels{buttonIdx}.Visible = 'On';
        end
        
        function m_EndGame(app)
            app.m_DisableAllButtons()
            app.m_BlinkBackgroundRed();
            app.m_DropTitle();
            app.m_DisplayTimeElasped();
            if sum(app.m_HitDelay) < 2000
                pause(4);
            end
        end
        
        function m_BlinkBackgroundRed(app)
            for i = 1:2
                app.UIFigure.Color = 'r';
                pause(0.2);
                app.UIFigure.Color = 'w';
                pause(0.2);
            end
        end
        
        function m_DropTitle(app)
            while app.lab_title.Position(2) > -10
                app.lab_title.Position(2) = app.lab_title.Position(2) -10;
                pause(0.01);
            end
        end
        
        function m_DisplayTimeElasped(app)
            app.lab_scoreBar.Visible = 'on';
            app.lab_scoreBar.Text =sprintf("You took %.3f seconds in total!", sum(app.m_HitDelay));
        end
        
        function m_DisableAllButtons(app)
            for i = 1:4
                app.m_Buttons{i}.Enable = "off";
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Program enters here
            app.lab_scoreBar.Visible = "off";
            app.m_RedButtonIdx = zeros(4, 1, "uint8");
            app.m_HitDelay = zeros(4, 1);
            app.m_RedTime = zeros(4, 1, 'uint64');
            app.m_HittedRedButton = zeros(4, 1, 'logical');
            app.m_Buttons = {app.but_tl, app.but_tr, app.but_bl, app.but_br};
            app.m_Labels = {app.lab_tl, app.lab_tr, app.lab_bl, app.lab_br};
            app.m_SetButtonIcon();
            app.AllRedButtonPressed = false; % initialize flag to false
            app.m_RedSeq = randperm(4); % get the random order for the button to turn red
            app.m_PlayGame(); % start main game
            app.m_EndGame();% display score
        end

        % Button pushed function: but_tl
        function but_tlButtonPushed(app, event)
            buttonIdx = uint8(1);
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_tr
        function but_trButtonPushed(app, event)
            buttonIdx = uint8(2);
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_bl
        function but_blButtonPushed(app, event)
            buttonIdx = uint8(3);
            app.m_MatchButtonPressed(buttonIdx);
        end

        % Button pushed function: but_br
        function but_brButtonPushed(app, event)
            buttonIdx = uint8(4);
            app.m_MatchButtonPressed(buttonIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.but_tl = uibutton(app.UIFigure, 'push');
            app.but_tl.ButtonPushedFcn = createCallbackFcn(app, @but_tlButtonPushed, true);
            app.but_tl.Text = '';
            app.but_tr = uibutton(app.UIFigure, 'push');
            app.but_tr.ButtonPushedFcn = createCallbackFcn(app, @but_trButtonPushed, true);
            app.but_tr.Text = '';
            app.but_bl = uibutton(app.UIFigure, 'push');
            app.but_bl.ButtonPushedFcn = createCallbackFcn(app, @but_blButtonPushed, true);
            app.but_bl.Text = '';
            app.but_br = uibutton(app.UIFigure, 'push');
            app.but_br.ButtonPushedFcn = createCallbackFcn(app, @but_brButtonPushed, true);
            app.but_br.Text = '';
            app.lab_title = uilabel(app.UIFigure);
            app.lab_title.Text = 'B10831020: Whack-a-mole';
            app.lab_tr = uilabel(app.UIFigure);
            app.lab_tr.Visible = 'off';
            app.lab_tl = uilabel(app.UIFigure);
            app.lab_tl.Visible = 'off';
            app.lab_br = uilabel(app.UIFigure);
            app.lab_br.Visible = 'off';
            app.lab_bl = uilabel(app.UIFigure);
            app.lab_bl.Visible = 'off';
            app.lab_scoreBar = uilabel(app.UIFigure);
            app.lab_scoreBar.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end