classdef B10831020_HW2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        SaveAsMenu            matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        ProcessMethodMenu     matlab.ui.container.Menu
        NegativeMenu          matlab.ui.container.Menu
        LRFlipMenu            matlab.ui.container.Menu
        DarkerMenu            matlab.ui.container.Menu
        DifferenceMenu        matlab.ui.container.Menu
        EdgeDetectMenu        matlab.ui.container.Menu
        HighPass              matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        PlayPauseBut          matlab.ui.control.StateButton
        CurrentTimeLab        matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        RightPanel            matlab.ui.container.Panel
        ProcessMethodNameLab  matlab.ui.control.Label
        Image_2               matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        m_VidObj                           % Video object for the selected video
        m_CurrentFrame             % Currently displayed frame
        m_Timer                          % Async timer to call the method processing each frame per 1/frameRate seconds
        m_PlayingFlag                 % Flag showing if the video is currently playing
        m_RemainingFrameCount  % Number of frames yet to be processed

        m_TempFrame               
        % An array of shape same as the m_CurrentFrame. For processing
        % method "difference", this array will be the previos frame. For
        % processing method "EdgeDetect", this array will be the result of
        % sobel filter stacked thrice.

        m_ProcessingMethodChoice   % Enum of the current frame
        m_ProcessingMethodHandel  % Function handle to the selected processing method function
    end
    
    methods (Access = private)
        
        function m_SetProcessImageMethod(app) 
            switch app.m_ProcessingMethodChoice
                case ProcessingMethod.Negative % invert the lightness of each pixel
                    app.ProcessMethodNameLab.Text = 'Negative';
                    app.m_ProcessingMethodHandel = @ProcessingMethod.MakeNegative;
                case ProcessingMethod.LR_Flip % mirror the image horizontally
                    app.ProcessMethodNameLab.Text = 'LR Flip';
                    app.m_ProcessingMethodHandel = @ProcessingMethod.MakeLRFlip;
                case ProcessingMethod.Darker % make the image darker
                    app.ProcessMethodNameLab.Text = 'Darker';
                    app.m_ProcessingMethodHandel = @ProcessingMethod.MakeDarker;
                case ProcessingMethod.Difference % show the difference between the previous frame
                    app.ProcessMethodNameLab.Text = 'Temporal Difference';
                    app.m_ProcessingMethodHandel = @ProcessingMethod.GetTemporalDifference;
                case ProcessingMethod.EdgeDetect
                    app.ProcessMethodNameLab.Text = "Edge Detection with Sobel Filter";
                    app.m_ProcessingMethodHandel = @ProcessingMethod.DetectEdge;
                case ProcessingMethod.HighPass
                    app.ProcessMethodNameLab.Text = "High Pass with Laplacian Filter\nwith erosion";
                    app.m_ProcessingMethodHandel = @ProcessingMethod.GetHighPass;
                case ProcessingMethod.None
                    app.ProcessMethodNameLab.Text = "Not processed";
                    app.m_ProcessingMethodHandel = @ProcessingMethod.DoNotProcess;
            end
            app.m_RenderProcessedImage();
        end
        
        function m_LoadFirstFrame(app, fName, pName)
            %             app.m_VidObj = VideoReader([pName, fName]);
            app.m_VidObj = VideoReader(pName+'\'+fName);
            app.m_CurrentFrame = read(app.m_VidObj, 1); %讀取第一幀的影像
            app.Image.ImageSource = app.m_CurrentFrame; %顯示到Image圖框
            app.m_TempFrame = zeros(size(app.m_CurrentFrame), "like", app.m_CurrentFrame);

            app.PlayPauseBut.Enable = "on";
            app.PlayPauseBut.Icon = "down.jpg";
            app.m_RemainingFrameCount = app.m_VidObj.NumFrames;
        end
       
        
        function m_PlayVideo(app)
            % Use an async timer to call the methods rendering each frame
            % periodically

            function processFrame(~, ~)
                % This function is called periodically by m_Timer. Use the static methods in class
                % ProcessMethod to render each frame.
                app.m_CurrentFrame = readFrame(app.m_VidObj);
                app.CurrentTimeLab.Text = num2str(app.m_VidObj.CurrentTime,'%.2f');
                app.Image.ImageSource = app.m_CurrentFrame;
                app.Image_2.ImageSource = app.m_ProcessingMethodHandel(app.m_CurrentFrame, app.m_TempFrame);
                pause(0.00001); % It is required to pause this render thread to show the processed image
            end
            function timerStartFn(~, ~)
                app.m_PlayingFlag = true;
            end

            app.m_Timer = timer("Period", 1/app.m_VidObj.FrameRate);
            app.m_Timer.StartFcn = @timerStartFn;
            app.m_Timer.TimerFcn = @processFrame;
            app.m_Timer.ExecutionMode = 'fixedRate';
            app.m_Timer.TasksToExecute = app.m_RemainingFrameCount;
            start(app.m_Timer);

        end
        
        function m_PauseVideo(app)
            % When the user pauses the video, update m_RemainingFrameCount
            % and stop the timer.
            stop(app.m_Timer);
            delete(app.m_Timer);
            app.m_RemainingFrameCount = round(app.m_RemainingFrameCount - app.m_VidObj.FrameRate * app.m_VidObj.CurrentTime);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
            app.Image.ScaleMethod = 'stretch';
            app.Image_2.ScaleMethod = 'stretch';
            app.m_ProcessingMethodChoice = ProcessingMethod.None;
            app.m_ProcessingMethodHandel = @ProcessingMethod.DoNotProcess;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %讀取視訊路徑檔名
%             [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            filename = "HW2_video_demo.mp4";
            pathname = "D:\NTUST_Not_Sync\ColorImageProcessingPratice\ColorImageProcessingPraticeHomework\HW2";
            app.m_LoadFirstFrame(filename, pathname);
            app.ProcessMethodMenu.Enable = "on";
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.Negative;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.LR_Flip;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.Darker;
            app.m_SetProcessImageMethod();
        end

        % Value changed function: PlayPauseBut
        function PlayPauseButValueChanged(app, event)
            if app.PlayPauseBut.Value
                app.PlayPauseBut.Icon = 'pause.jpg';
                app.m_PlayingFlag = true;
                app.m_PlayVideo();
            else
                app.PlayPauseBut.Icon = 'down.jpg';
                if app.m_PlayingFlag
                    app.m_PauseVideo();
                end
                app.m_PlayingFlag = false;
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [filename, pathname] = uiputfile({'*.mp4'},'Save a video file');
            vidObj2 = VideoWriter([pathname filename],'MPEG-4');
            vidObj2.Quality = 90; % default quality = 75
            open(vidObj2)
            read(app.m_VidObj,1);
            
            while hasFrame(app.m_VidObj)                
                writeVideo(vidObj2, readFrame(app.m_VidObj));
            end
            close(vidObj2);            
        end

        % Menu selected function: DifferenceMenu
        function DifferenceMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.Difference;
            % initialize the temp frame array with the current frame so the
            % difference between future frames can be compared
            app.m_TempFrame = app.m_CurrentFrame;  
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: EdgeDetectMenu
        function EdgeDetectMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.EdgeDetect;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: HighPass
        function HighPassMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.HighPass;
            app.m_SetProcessImageMethod();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMethodMenu = uimenu(app.UIFigure);
            app.ProcessMethodMenu.Enable = 'off';
            app.ProcessMethodMenu.Text = 'ProcessMethod';
            app.NegativeMenu = uimenu(app.ProcessMethodMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.ProcessMethodMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.ProcessMethodMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.DifferenceMenu = uimenu(app.ProcessMethodMenu);
            app.DifferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferenceMenuSelected, true);
            app.DifferenceMenu.Text = 'Difference';
            app.EdgeDetectMenu = uimenu(app.ProcessMethodMenu);
            app.EdgeDetectMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeDetectMenuSelected, true);
            app.EdgeDetectMenu.Text = 'Edge Detect';
            app.HighPass = uimenu(app.ProcessMethodMenu);
            app.HighPass.MenuSelectedFcn = createCallbackFcn(app, @HighPassMenuSelected, true);
            app.HighPass.Text = 'High Pass';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.Enable = 'off';
            app.CurrentTimeLab = uilabel(app.LeftPanel);
            app.CurrentTimeLab.Text = 'CurrentTime';
            app.PlayPauseBut = uibutton(app.LeftPanel, 'state');
            app.PlayPauseBut.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButValueChanged, true);
            app.PlayPauseBut.Enable = 'off';
            app.PlayPauseBut.Icon = 'pause.jpg';
            app.PlayPauseBut.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Image_2.Enable = 'off';
            app.ProcessMethodNameLab = uilabel(app.RightPanel);
            app.ProcessMethodNameLab.Text = 'Processsing Method Name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B10831020_HW2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end