classdef B10831020_HW2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        SaveAsMenu            matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        ProcessMethodMenu     matlab.ui.container.Menu
        NegativeMenu          matlab.ui.container.Menu
        LRFlipMenu            matlab.ui.container.Menu
        DarkerMenu            matlab.ui.container.Menu
        EdgeDetectMenu        matlab.ui.container.Menu
        HighPassMenu          matlab.ui.container.Menu
        NotProcessedMenu      matlab.ui.container.Menu
        WaterMarkMenu         matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        DeleteButton          matlab.ui.control.StateButton
        ProgressSld           matlab.ui.control.Slider
        Label                 matlab.ui.control.Label
        PlayPauseBut          matlab.ui.control.StateButton
        CurrentTimeLab        matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        RightPanel            matlab.ui.container.Panel
        DeletedLab            matlab.ui.control.Label
        ProcessMethodNameLab  matlab.ui.control.Label
        Image_2               matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        m_VidObj                        % Video object for the selected video
        m_Height                        % Height of the video
        m_Width                         % Width of the video
        m_CurrentFrame             % Currently displayed frame
        m_Timer                          % Async timer calling the method method processFrame once per 1/frameRate seconds
        m_PlayingFlag                 % Flag showing if the video is currently playing
        m_RemainingFrameCount  % Number of frames yet to be displayed
        m_DeleteTimeStamps     % Cell array containing structs of begin and end time stamps of video segments to be deleted
        m_DeleteButtonActiveFlag % Flag showing if the delete button is currently pressed down

        m_ProcessingMethodLog
        % Cell array containing structs of begin time stamp of video 
        % segments to be processed by a method in ProcessMethod, as well as
        % the method handle
        
        m_ProcessingMethodChoice   
        % The enum of the currently using processing method. The enum is
        % written in another file ProcessingMethod.m

        m_ProcessingMethodHandel  
        % Function handle to the selected processing method function
        % All the processing method function are written in another file
        % ProcessingMethod.m
        
        m_WatermarkText = "B10831020";
        m_SaveasWaitBar             % Waitbar object displaying the export progress
        m_SaveasProgress             % The name is self-explanitory
    end
    
    methods (Access = private)
        
        function m_SetProcessImageMethod(app) 
            % When the user changes the processing mthod, call this
            % function to set m_ProcessingMethodHandel and
            % m_ProcessingMethodNameLab. Also, log the timestamp and the
            % selected processing method to m_ProcessingMethodLog as a
 
            switch app.m_ProcessingMethodChoice
                case ProcessingMethod.Negative % invert the lightness of each pixel
                    app.ProcessMethodNameLab.Text = 'Negative';
                    app.m_ProcessingMethodHandel = @ProcessingMethod.MakeNegative;
                case ProcessingMethod.LR_Flip % mirror the image horizontally
                    app.ProcessMethodNameLab.Text = 'LR Flip';
                    app.m_ProcessingMethodHandel = @ProcessingMethod.MakeLRFlip;
                case ProcessingMethod.Darker % make the image darker
                    app.ProcessMethodNameLab.Text = 'Darker';
                    app.m_ProcessingMethodHandel = @ProcessingMethod.MakeDarker;
                case ProcessingMethod.EdgeDetect
                    app.ProcessMethodNameLab.Text = "Edge Detection with Sobel Filter";
                    app.m_ProcessingMethodHandel = @ProcessingMethod.DetectEdge;
                case ProcessingMethod.HighPass
                    app.ProcessMethodNameLab.Text = "High Pass with Laplacian Filter\nwith erosion";
                    app.m_ProcessingMethodHandel = @ProcessingMethod.GetHighPass;
                case ProcessingMethod.None
                    app.ProcessMethodNameLab.Text = "Not processed";
                    app.m_ProcessingMethodHandel = @ProcessingMethod.DoNotProcess;
            end
            % call the selected image processing method by its handle and
            % set the result to be the Image_2.ImageSource
            app.Image_2.ImageSource = app.m_ProcessingMethodHandel(abs(app.m_CurrentFrame - 100 * app.m_DeleteButtonActiveFlag));

            % log the image processing configuration and start time stamp
            % into cell array m_ProcessingMethodLog as struct fields
            % "ProcessStartTime" and "MethodHandle"
            app.m_ProcessingMethodLog{end+1} = struct( ...
                "ProcessStartTime", app.m_VidObj.CurrentTime, ...
                "MethodHandle", app.m_ProcessingMethodHandel);
        end
        
        function m_LoadFirstFrame(app, filePath)
            app.m_VidObj = VideoReader(filePath);
            app.m_Width = app.m_VidObj.Width;
            app.m_Height = app.m_VidObj.Height;
            app.m_CurrentFrame = read(app.m_VidObj, 1); %讀取第一幀的影像
            app.Image.ImageSource = app.m_CurrentFrame; %顯示到Image圖框

            app.DeleteButton.Enable = "on";
            app.PlayPauseBut.Enable = "on";
            app.CurrentTimeLab.Visible = "on";
            app.PlayPauseBut.Icon = "down.jpg";
            app.m_InitializeProgressSlider();
            app.m_ProcessingMethodLog{end+1} = struct( ...
                "ProcessStartTime", 0,...
                "MethodHandle", @ProcessingMethod.DoNotProcess);
            app.m_RemainingFrameCount = app.m_VidObj.NumFrames;
        end
       
        
        function m_PlayVideo(app)
            % Use an async timer m_Timer to call the methods rendering each frame
            % periodically. When the user stops the video or moves the progress bar
            % , the m_Timer is destroyed. and recreated if the video starts playing
            % afterwards.

            function processFrame(~, ~)
                % This function is called periodically by m_Timer. Use the static methods in class
                % ProcessMethod to render each frame.
                app.m_CurrentFrame = readFrame(app.m_VidObj);
                app.Image.ImageSource = app.m_CurrentFrame;
                app.CurrentTimeLab.Text = num2str(app.m_VidObj.CurrentTime,'%.2f');
                app.Image_2.ImageSource = abs(app.m_ProcessingMethodHandel(app.m_CurrentFrame) - 100 * app.m_DeleteButtonActiveFlag);
                app.ProgressSld.Value = app.m_VidObj.CurrentTIme;
                pause(0.00000000001); % It is required to pause this render thread to show the processed image
            end
            function timerStartFn(~, ~)
                app.m_PlayingFlag = true;
            end
            function timerEndFn(~, ~)
                app.m_PlayingFlag = false;
                app.PlayPauseBut.Icon = "down.jpg";
            end

            app.m_Timer = timer("Period", 1/app.m_VidObj.FrameRate);
            app.m_Timer.StartFcn = @timerStartFn;
            app.m_Timer.TimerFcn = @processFrame;
            app.m_Timer.StopFcn = @timerEndFn;
            app.m_Timer.ExecutionMode = 'fixedRate';
            app.m_Timer.TasksToExecute = round(app.m_RemainingFrameCount - app.m_VidObj.FrameRate * app.m_VidObj.CurrentTime);
            start(app.m_Timer);

        end
       
        function m_InitializeProgressSlider(app)
            app.ProgressSld.Limits = [0, app.m_VidObj.Duration];
            app.ProgressSld.Value = 0;
            app.ProgressSld.MajorTicks =round(linspace(0, app.m_VidObj.Duration, 10));
            app.ProgressSld.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; close all;
            app.ProgressSld.Enable = "off";
            app.Image.ScaleMethod = 'stretch';
            app.Image_2.ScaleMethod = 'stretch';
            app.m_DeleteButtonActiveFlag = false;
            app.m_ProcessingMethodChoice = ProcessingMethod.None;
            app.m_ProcessingMethodHandel = @ProcessingMethod.DoNotProcess;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % Export the video. First render all the frames with the
            % selected processing method. Save all the processed frames to
            % local array renderedFrames. Then from app.m_DeleteTimeStamp,
            % determine the frames to exclude from the exported video.
            
            [filename, pathname] = uiputfile({'*.mp4'},'Save a video file');
            if filename == "" && pathname == ""
                % if the user shuts down the ui getfile window, do not
                % proceed
                return
            end
            exportLocation = fullfile(pathname, filename);

            % stop the video and make ui objects uninteractive
            function makeUINonInteractive()
                
                if app.m_PlayingFlag
                    stop(app.m_Timer);
                    delete(app.m_Timer);
                end
                app.OpenMenu.Enable = "off";
                app.ProcessMethodMenu.Enable = "off";
                app.LeftPanel.Enable = "off";

                % get the file name to save the video from user
                app.ProgressSld.Enable = "off";
                app.FileMenu.Enable = "off";
                app.ProcessMethodMenu.Enable = "off";
            end

            makeUINonInteractive();
            % create a waitbar showing exporting progress
            app.m_SaveasProgress = 0;
            app.m_SaveasWaitBar = waitbar(app.m_SaveasProgress, "Rendering frames....");

            function result = renderAllFramesWithEffects()
                [~, processingMethodCount] = size(app.m_ProcessingMethodLog);
                app.m_VidObj.CurrentTime = 0;
                result = zeros(app.m_VidObj.NumFrames, app.m_VidObj.Height, app.m_VidObj.Width, 3, 'uint8');
                frameIdx = 1;
                processingPartIdx = 1;
                tmpFrame = zeros(app.m_Height, app.m_Width, 3, "uint8");
                while hasFrame(app.m_VidObj)
                    if  round(app.m_ProcessingMethodLog{processingPartIdx}.ProcessStartTime, 4) == round(app.m_VidObj.CurrentTime, 4)
                        processingMethodHandle = app.m_ProcessingMethodLog{processingPartIdx}.MethodHandle;
                        if processingPartIdx < processingMethodCount
                            processingPartIdx = processingPartIdx + 1;
                        end
                    end
                    result(frameIdx, :, :, :) = processingMethodHandle(readFrame(app.m_VidObj));

                    % add water mark
                    % thank you matlab for making the result of indexing array
                    % by renderedFrames(frameIdx) as 4D array of size (1,
                    % Height, Wdith, 3) instead of as 3D array of size (Heigth, Width, 3).
                    % It makes so much sense!
                    tmpFrame(: ,: ,:) = result(frameIdx, :, :, :);
                    result(frameIdx, :, :, :) = insertText(tmpFrame, [5, 10], app.m_WatermarkText, "FontSize", 18);
                    frameIdx = frameIdx + 1;

                    % update waitbar progress
                    % when all the image processing is done, the  wait
                    % bar will be at 50%
                    app.m_SaveasProgress = (app.m_VidObj.CurrentTime / app.m_VidObj.Duration) * 0.5;
                    waitbar(app.m_SaveasProgress, app.m_SaveasWaitBar);
                end
            end
    
            renderedFrames = renderAllFramesWithEffects();

            % if the user doesn't release the delete button before pressing
            % saveas, assume the reset of the footage is meant to be
            % deleted
            if not(isempty(app.m_DeleteTimeStamps))
                if not(isfield(app.m_DeleteTimeStamps{end}, "delEnd"))
                    app.m_DeleteTimeStamps{end}.delEnd = app.m_VidObj.Duration;
                end
            end

            % if the user press the delete button, move the progress bar in
            % the opposite direction, the delStart time may be larger than
            % delEnd time. If so, swap the two values
            [~, delPartCount] = size(app.m_DeleteTimeStamps);
            for i = 1:delPartCount
                if app.m_DeleteTimeStamps{i}.delEnd < app.m_DeleteTimeStamps{i}.delStart
                    tmp = app.m_DeleteTimeStamps{i}.delEnd;
                    app.m_DeleteTimeStamps{i}.delEnd = app.m_DeleteTimeStamps{i}.delStart;
                    app.m_DeleteTimeStamps{i}.delStart = tmp;
                end
            end
            
            % display the delete time stamps in command window
            for i = 1:delPartCount
                fprintf("Delete timestamp %d: %.3f ~ %.3f\n", i, app.m_DeleteTimeStamps{i}.delStart, app.m_DeleteTimeStamps{i}.delEnd);
            end

            waitbar(app.m_SaveasProgress, app.m_SaveasWaitBar, "Trimming video segments");

            function trimVideoAndExport(exportFilePath, allFrames, numDelPart)
                vidObjOut = VideoWriter(exportFilePath,'MPEG-4');
                vidObjOut.Quality = 75;
                vidObjOut.FrameRate = app.m_VidObj.FrameRate;
                open(vidObjOut);
                delPartIdx = 1;
                frameIdx = 1;
                app.m_VidObj.CurrentTime = 0;
                while app.m_VidObj.CurrentTime < app.m_VidObj.Duration
                    readFrame(app.m_VidObj);
                    writeVideo(vidObjOut, reshape(allFrames(frameIdx, :, :, :), app.m_Height, app.m_Width, 3));
                    if delPartIdx <= numDelPart
                        if round(app.m_VidObj.CurrentTime, 4) == round(app.m_DeleteTimeStamps{delPartIdx}.delStart, 4)
                            app.m_VidObj.CurrentTime = app.m_DeleteTimeStamps{delPartIdx}.delEnd;
                            frameIdx = round(app.m_VidObj.CurrentTime * app.m_VidObj.FrameRate);
                            delPartIdx = delPartIdx + 1;
                            continue
                        end
                    end
                    frameIdx =  round(app.m_VidObj.CurrentTime * app.m_VidObj.FrameRate);
                    app.m_SaveasProgress = 0.5 + (app.m_VidObj.CurrentTime / app.m_VidObj.Duration) * 0.5;
                    waitbar(app.m_SaveasProgress, app.m_SaveasWaitBar);
                end
                close(vidObjOut);
            end

            trimVideoAndExport(exportLocation, renderedFrames, delPartCount);

            waitbar(1, app.m_SaveasWaitBar, "Saving video to disk...");
            fprintf("File has been exported to %s\n", exportLocation);
            close(app.m_SaveasWaitBar);
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file');
            if filename == "" && pathname == ""
                % if the user shuts down the ui getfile window, do not
                % proceed
                return
            end
            app.m_LoadFirstFrame(fullfile(pathname, filename));
            app.ProcessMethodMenu.Enable = "on";
            app.SaveAsMenu.Enable = "on";
            app.OpenMenu.Enable = "off";
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.Negative;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.LR_Flip;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.Darker;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: EdgeDetectMenu
        function EdgeDetectMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.EdgeDetect;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: HighPassMenu
        function HighPassMenuMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.HighPass;
            app.m_SetProcessImageMethod();
        end

        % Value changed function: PlayPauseBut
        function PlayPauseButValueChanged(app, event)
            if app.PlayPauseBut.Value
                app.PlayPauseBut.Icon = 'pause.jpg';
                app.m_PlayingFlag = true;
                app.m_PlayVideo();
            else
                app.PlayPauseBut.Icon = 'down.jpg';
                if app.m_PlayingFlag
                    stop(app.m_Timer);
                    delete(app.m_Timer);
                end
                app.m_PlayingFlag = false;
            end
        end

        % Value changed function: ProgressSld
        function ProgressSldValueChanged(app, event)
            app.m_VidObj.CurrentTime = app.ProgressSld.Value;
            if app.m_PlayingFlag
                stop(app.m_Timer);
                delete(app.m_Timer);
                app.m_PlayVideo();
            end
        end

        % Value changing function: ProgressSld
        function ProgressSldValueChanging(app, event)

        end

        % Value changed function: DeleteButton
        function DeleteButtonValueChanged(app, event)
            app.m_DeleteButtonActiveFlag = app.DeleteButton.Value;
            if app.m_DeleteButtonActiveFlag
                % if the delete button is pushed down, add a new struct to
                % cell array app.m_DeleteTimeStamps, and append the current
                % time stamp as the "delStart" of the struct
                app.m_DeleteTimeStamps{end+1}.delStart = app.m_VidObj.CurrentTime;
                app.DeletedLab.Visible = "on";
            else
                % if the delete button is released, store the current time
                % stamp to the latest created struct in cell array
                % m_DeleteTimeStamps  as member "delEnd"
                app.m_DeleteTimeStamps{end}.delEnd = app.m_VidObj.CurrentTime;
                app.DeletedLab.Visible = "off";
            end
        end

        % Menu selected function: NotProcessedMenu
        function NotProcessedMenuSelected(app, event)
            app.m_ProcessingMethodChoice = ProcessingMethod.None;
            app.m_SetProcessImageMethod();
        end

        % Menu selected function: WaterMarkMenu
        function WaterMarkMenuSelected(app, event)
            userInput = inputdlg("Set watermark text", "Watermark", [1, 36], app.m_WatermarkText);
            if not(userInput == "")
                app.m_WatermarkText = userInput; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Enable = 'off';
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMethodMenu = uimenu(app.UIFigure);
            app.ProcessMethodMenu.Enable = 'off';
            app.ProcessMethodMenu.Text = 'Process Method';
            app.NegativeMenu = uimenu(app.ProcessMethodMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.ProcessMethodMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.ProcessMethodMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.EdgeDetectMenu = uimenu(app.ProcessMethodMenu);
            app.EdgeDetectMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeDetectMenuSelected, true);
            app.EdgeDetectMenu.Text = 'Edge Detect';
            app.HighPassMenu = uimenu(app.ProcessMethodMenu);
            app.HighPassMenu.MenuSelectedFcn = createCallbackFcn(app, @HighPassMenuMenuSelected, true);
            app.HighPassMenu.Text = 'High Pass';
            app.NotProcessedMenu = uimenu(app.ProcessMethodMenu);
            app.NotProcessedMenu.MenuSelectedFcn = createCallbackFcn(app, @NotProcessedMenuSelected, true);
            app.NotProcessedMenu.Text = 'Not Processed';
            app.WaterMarkMenu = uimenu(app.UIFigure);
            app.WaterMarkMenu.MenuSelectedFcn = createCallbackFcn(app, @WaterMarkMenuSelected, true);
            app.WaterMarkMenu.Text = 'Watermark';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.Enable = 'off';
            app.CurrentTimeLab = uilabel(app.LeftPanel);
            app.CurrentTimeLab.Visible = 'off';
            app.CurrentTimeLab.Text = 'CurrentTime';
            app.PlayPauseBut = uibutton(app.LeftPanel, 'state');
            app.PlayPauseBut.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButValueChanged, true);
            app.PlayPauseBut.Enable = 'off';
            app.PlayPauseBut.Icon = 'pause.jpg';
            app.PlayPauseBut.Text = '';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.ProgressSld = uislider(app.LeftPanel);
            app.ProgressSld.MajorTicks = [];
            app.ProgressSld.ValueChangedFcn = createCallbackFcn(app, @ProgressSldValueChanged, true);
            app.ProgressSld.ValueChangingFcn = createCallbackFcn(app, @ProgressSldValueChanging, true);
            app.ProgressSld.MinorTicks = [];
            app.DeleteButton = uibutton(app.LeftPanel, 'state');
            app.DeleteButton.ValueChangedFcn = createCallbackFcn(app, @DeleteButtonValueChanged, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Image_2.Enable = 'off';
            app.ProcessMethodNameLab = uilabel(app.RightPanel);
            app.ProcessMethodNameLab.Text = 'no processing method selected';
            app.DeletedLab = uilabel(app.RightPanel);
            app.DeletedLab.Visible = 'off';
            app.DeletedLab.Text = 'Segment excluded';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B10831020_HW2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end