classdef app24_vid_proc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        SaveAsMenu            matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        ProcessMethodMenu     matlab.ui.container.Menu
        NegativeMenu          matlab.ui.container.Menu
        LRFlipMenu            matlab.ui.container.Menu
        DarkerMenu            matlab.ui.container.Menu
        DifferenceMenu        matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        PlayPauseBut          matlab.ui.control.StateButton
        CurrentTimeLab        matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        RightPanel            matlab.ui.container.Panel
        ProcessMethodNameLab  matlab.ui.control.Label
        Image_2               matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        m_VidObj                           % Video object for the selected video
        m_CurrentFrame                % Currently displayed frame
        m_PreFrame                       % Previouly displayed frame
        m_ProcessingMethodIdx   % Index of the current frame
    end
    
    methods (Access = private)
        
        function m_ProcessImage(app) %影像處理
            switch app.m_ProcessingMethodIdx
                case 1 %反白
                    app.Image_2.ImageSource = 255- app.m_CurrentFrame;
                    app.ProcessMethodNameLab.Text = 'Negative';
                case 2 %左右翻轉
                    app.Image_2.ImageSource = fliplr(app.m_CurrentFrame);
                    app.ProcessMethodNameLab.Text = 'LR Flip';
                case 3 %變暗
                    app.Image_2.ImageSource = app.m_CurrentFrame - 64;
                    app.ProcessMethodNameLab.Text = 'Darker';
                case 4 %影像時間差異
                    app.Image_2.ImageSource = imabsdiff(app.m_CurrentFrame,app.m_PreFrame); %跟前畫面的差異
                    app.m_PreFrame = app.m_CurrentFrame;
                    app.ProcessMethodNameLab.Text = 'Temporal Difference';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Image.ScaleMethod = 'stretch';
            app.Image_2.ScaleMethod = 'stretch';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {547, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            %讀取視訊路徑檔名
            [filename, pathname]  = uigetfile({'*.mp4';'*.avi';'*.mpg'},'read a video file'); 
            %建立是視訊物件
            app.m_VidObj = VideoReader([pathname filename]);
            app.m_CurrentFrame = read(app.m_VidObj, 1); %讀取第一幀的影像
            app.Image.ImageSource = app.m_CurrentFrame; %顯示到Image圖框
            app.m_PreFrame = app.m_CurrentFrame; %暫存(用於比較影像差異)
            m_ProcessImage(app); 
            app.PlayPauseBut.Icon = 'down.jpg';
            app.CurrentTimeLab.Text = '0';
            figure(app.UIFigure)
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: NegativeMenu
        function NegativeMenuSelected(app, event)
            app.m_ProcessingMethodIdx = 1;
%             m_ProcessImage(app);
        end

        % Menu selected function: LRFlipMenu
        function LRFlipMenuSelected(app, event)
            app.m_ProcessingMethodIdx = 2;
%             m_ProcessImage(app);
        end

        % Menu selected function: DarkerMenu
        function DarkerMenuSelected(app, event)
            app.m_ProcessingMethodIdx = 3;
%             m_ProcessImage(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            read(app.m_VidObj,1);
            app.PlayPauseBut.Icon = 'pause.jpg';

            while hasFrame(app.m_VidObj)
                app.m_CurrentFrame = readFrame(app.m_VidObj);
                pause(1/app.m_VidObj.FrameRate)
                app.CurrentTimeLab.Text = num2str(app.m_VidObj.CurrentTime);
                app.Image.ImageSource = app.m_CurrentFrame;
                m_ProcessImage(app);
            end
            app.PlayPauseBut.Icon = 'down.jpg';
        end

        % Value changed function: PlayPauseBut
        function PlayPauseButValueChanged(app, event)
            value = app.PlayPauseBut.Value;
            if value
                read(app.m_VidObj,1);
                app.PlayPauseBut.Icon = 'pause.jpg';
                
                while hasFrame(app.m_VidObj)
                    if app.PlayPauseBut.Value == 0
                        break;
                    end
                    app.m_CurrentFrame = readFrame(app.m_VidObj);
                    pause(1/app.m_VidObj.FrameRate)
                    app.CurrentTimeLab.Text = num2str(app.m_VidObj.CurrentTime,'%.2f');
                    app.Image.ImageSource = app.m_CurrentFrame;
                    m_ProcessImage(app);
                end
            else
                app.PlayPauseBut.Icon = 'down.jpg';
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [filename, pathname] = uiputfile({'*.mp4'},'Save a video file');
            vidObj2 = VideoWriter([pathname filename],'MPEG-4');
            vidObj2.Quality = 90; % default quality = 75
            open(vidObj2)
            read(app.m_VidObj,1);
            
            while hasFrame(app.m_VidObj)                
                writeVideo(vidObj2, readFrame(app.m_VidObj));
            end
            close(vidObj2);            
        end

        % Menu selected function: DifferenceMenu
        function DifferenceMenuSelected(app, event)
            app.m_ProcessingMethodIdx = 4;
            m_ProcessImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ProcessMethodMenu = uimenu(app.UIFigure);
            app.ProcessMethodMenu.Enable = 'off';
            app.ProcessMethodMenu.Text = 'ProcessMethod';
            app.NegativeMenu = uimenu(app.ProcessMethodMenu);
            app.NegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @NegativeMenuSelected, true);
            app.NegativeMenu.Text = 'Negative';
            app.LRFlipMenu = uimenu(app.ProcessMethodMenu);
            app.LRFlipMenu.MenuSelectedFcn = createCallbackFcn(app, @LRFlipMenuSelected, true);
            app.LRFlipMenu.Text = 'LR Flip';
            app.DarkerMenu = uimenu(app.ProcessMethodMenu);
            app.DarkerMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkerMenuSelected, true);
            app.DarkerMenu.Text = 'Darker';
            app.DifferenceMenu = uimenu(app.ProcessMethodMenu);
            app.DifferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @DifferenceMenuSelected, true);
            app.DifferenceMenu.Text = 'Difference';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.Enable = 'off';
            app.CurrentTimeLab = uilabel(app.LeftPanel);
            app.CurrentTimeLab.Text = 'CurrentTime';
            app.PlayPauseBut = uibutton(app.LeftPanel, 'state');
            app.PlayPauseBut.ValueChangedFcn = createCallbackFcn(app, @PlayPauseButValueChanged, true);
            app.PlayPauseBut.Enable = 'off';
            app.PlayPauseBut.Icon = 'pause.jpg';
            app.PlayPauseBut.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Image_2.Enable = 'off';
            app.ProcessMethodNameLab = uilabel(app.RightPanel);
            app.ProcessMethodNameLab.Text = 'Processsing Method Name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app24_vid_proc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end